classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes2                         matlab.ui.control.UIAxes
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        TileLabel                       matlab.ui.control.Label
        TileDropDown                    matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        DurationSliderLabel             matlab.ui.control.Label
        DurationSlider                  matlab.ui.control.Slider
        CalculateButton                 matlab.ui.control.Button
        numberofspatialstepsEditFieldLabel  matlab.ui.control.Label
        numberofspatialstepsEditField   matlab.ui.control.NumericEditField
        numeroftimestepsEditFieldLabel  matlab.ui.control.Label
        numberoftimestepsEditField      matlab.ui.control.NumericEditField
        thicknessEditFieldLabel         matlab.ui.control.Label
        thicknessEditField              matlab.ui.control.NumericEditField
    end

       
    properties (Access = public)
        tmax % maximum time
        xmax % thickness
        nx % number of spatial steps
        nt% number of time steps
        method% method
        tile % tile
        
    end
    

    methods (Access = private)

        % Value changed function: TileDropDown
        function TileDropDownValueChanged(app, event)
            app.tile = app.TileDropDown.Value;
            %app.tile = num2str(app.TileDropDown.Value);
            switch app.tile
                case '468'
                    app.Button.Icon = '468.png';
                case '480'
                    app.Button.Icon = '480.png';
                case ' 502'
                    app.Button.Icon = '502.png';
                case '590'
                    app.Button.Icon = '590.png';
                case '597'
                    app.Button.Icon = '597.png';
                case '711'
                    app.Button.Icon = '711.png';
                case '730'
                    app.Button.Icon = '730.png';
                case '850'
                    app.Button.Icon = '850.png';
            end
                    
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.method = app.MethodDropDown.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.nx = app.numberofspatialstepsEditField.Value;
            app.nt = app.numberoftimestepsEditField.Value;
            app.xmax = app.thicknessEditField.Value;
            app.method = app.MethodDropDown.Value;
            app.tile = app.TileDropDown.Value;


            [x, t, u] = shuttleNoPlot(app.tmax, app.nt, app.xmax, app.nx, app.method, app.tile);
            surf(app.UIAxes2,x,t,u,'LineStyle','none');
            %set(app.UIAxes2,'LineStyle','none')
            
            
            % comment out the next line to change the surface appearance
            shading interp 
    
            % Rotate the view
            view(150,30)
    
            %label the axes
            xlabel(app.UIAxes2,'\itx\rm - m')
            ylabel(app.UIAxes2,'\itt\rm - s')
            zlabel(app.UIAxes2,'\itu\rm - deg C')
        switch app.method
            case 'forward'
                title(app.UIAxes2,'Forward Differencing')
            case'dufort-frankel'
                title(app.UIAxes2,'Dufort-Frankel')
            case 'backward'
                title(app.UIAxes2,'Backwards Differencing')
            case 'crank-nicolson'
                title(app.UIAxes2,'Crank-Nicolson')
    
        end
        end

        % Value changed function: DurationSlider
        function DurationSliderValueChanged(app, event)
            app.tmax = app.DurationSlider.Value;
            
        end

        % Value changed function: numberofspatialstepsEditField
        function numberofspatialstepsEditFieldValueChanged(app, event)
            app.nx = app.numberofspatialstepsEditField.Value;
            
        end

        % Value changed function: numberoftimestepsEditField
        function numberoftimestepsEditFieldValueChanged(app, event)
            app.nt = app.numberoftimestepsEditField.Value;
            
        end

        % Value changed function: thicknessEditField
        function thicknessEditFieldValueChanged(app, event)
            app.xmax = app.thicknessEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'forward', 'dufort-frankel', 'backward', 'crank-nicolson'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'forward';
            app.TileLabel = uilabel(app.UIFigure);
            app.TileLabel.Text = 'Tile';
            app.TileDropDown = uidropdown(app.UIFigure);
            app.TileDropDown.Items = {'468', '502', '597', '711', '730', '850'};
            app.TileDropDown.ValueChangedFcn = createCallbackFcn(app, @TileDropDownValueChanged, true);
            app.TileDropDown.Value = '468';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'shuttle.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.DurationSliderLabel = uilabel(app.UIFigure);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.UIFigure);
            app.DurationSlider.Limits = [0 4000];
            app.DurationSlider.ValueChangedFcn = createCallbackFcn(app, @DurationSliderValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.numberofspatialstepsEditFieldLabel = uilabel(app.UIFigure);
            app.numberofspatialstepsEditFieldLabel.Text = 'number of spatial steps';
            app.numberofspatialstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberofspatialstepsEditField.ValueChangedFcn = createCallbackFcn(app, @numberofspatialstepsEditFieldValueChanged, true);
            app.numberofspatialstepsEditField.Value = 21;
            app.numeroftimestepsEditFieldLabel = uilabel(app.UIFigure);
            app.numeroftimestepsEditFieldLabel.Text = 'numer of timesteps';
            app.numberoftimestepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberoftimestepsEditField.ValueChangedFcn = createCallbackFcn(app, @numberoftimestepsEditFieldValueChanged, true);
            app.numberoftimestepsEditField.Value = 501;
            app.thicknessEditFieldLabel = uilabel(app.UIFigure);
            app.thicknessEditFieldLabel.Text = 'thickness';
            app.thicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.thicknessEditField.ValueChangedFcn = createCallbackFcn(app, @thicknessEditFieldValueChanged, true);
            app.thicknessEditField.Value = 0.05;
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end